classdef GridLab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GridLaboratoryUIFigure       matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        MotorParametersTab           matlab.ui.container.Tab
        GridLayout5                  matlab.ui.container.GridLayout
        Panel2                       matlab.ui.container.Panel
        GridLayout7                  matlab.ui.container.GridLayout
        FrequencySliderLabel         matlab.ui.control.Label
        FrequencySlider              matlab.ui.control.Slider
        EditFrequence                matlab.ui.control.EditField
        PolesSpinnerLabel            matlab.ui.control.Label
        PolesSpinner                 matlab.ui.control.Spinner
        Image                        matlab.ui.control.Image
        PHASEINDUCTIONMOTORLabel     matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        PWMInverterPanel             matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        Panel5                       matlab.ui.container.Panel
        GridLayout13                 matlab.ui.container.GridLayout
        VEditField_11Label           matlab.ui.control.Label
        VEditField_11                matlab.ui.control.EditField
        mfEditFieldLabel             matlab.ui.control.Label
        mfEditField                  matlab.ui.control.EditField
        maEditFieldLabel             matlab.ui.control.Label
        maEditField                  matlab.ui.control.EditField
        VoltageVKnob                 matlab.ui.control.Knob
        VbLamp_11                    matlab.ui.control.Lamp
        VALabel                      matlab.ui.control.Label
        VbLamp_12                    matlab.ui.control.Lamp
        VbLamp_13                    matlab.ui.control.Lamp
        VBLabel                      matlab.ui.control.Label
        VCLabel                      matlab.ui.control.Label
        MotorLoadPanel_2             matlab.ui.container.Panel
        GridLayout10_2               matlab.ui.container.GridLayout
        LoadKnob_2Label              matlab.ui.control.Label
        LoadMotor                    matlab.ui.control.Knob
        ValueEditFieldLabel          matlab.ui.control.Label
        ValueLoadMotor               matlab.ui.control.EditField
        SetParametersButton          matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        GridLayout6                  matlab.ui.container.GridLayout
        RsEditFieldLabel             matlab.ui.control.Label
        RsEditField                  matlab.ui.control.NumericEditField
        XsEditFieldLabel             matlab.ui.control.Label
        XsEditField                  matlab.ui.control.NumericEditField
        XmEditFieldLabel             matlab.ui.control.Label
        XmEditField                  matlab.ui.control.NumericEditField
        XrEditFieldLabel             matlab.ui.control.Label
        XrEditField                  matlab.ui.control.NumericEditField
        RrsEditFieldLabel            matlab.ui.control.Label
        RrsEditField                 matlab.ui.control.NumericEditField
        GraphicalResultsTab          matlab.ui.container.Tab
        GridLayout4                  matlab.ui.container.GridLayout
        ExportDropDownLabel          matlab.ui.control.Label
        ExportDropDown               matlab.ui.control.DropDown
        UIAxes_Stator_current        matlab.ui.control.UIAxes
        UIAxes_Rotor_current         matlab.ui.control.UIAxes
        UIAxes_EM_torque             matlab.ui.control.UIAxes
        UIAxes_speed_vs_torque       matlab.ui.control.UIAxes
        UIAxes_pwm                   matlab.ui.control.UIAxes
        ReadMeTab                    matlab.ui.container.Tab
        GridLayout9                  matlab.ui.container.GridLayout
        ModeTextAreaLabel            matlab.ui.control.Label
        ModeTextArea                 matlab.ui.control.TextArea
        MotorTextAreaLabel           matlab.ui.control.Label
        MotorTextArea                matlab.ui.control.TextArea
        PWMTextAreaLabel             matlab.ui.control.Label
        PWMTextArea                  matlab.ui.control.TextArea
        HelpTextAreaLabel            matlab.ui.control.Label
        HelpTextArea                 matlab.ui.control.TextArea
        MotorTextArea_2Label         matlab.ui.control.Label
        MotorTextArea_2              matlab.ui.control.TextArea
        RelevantInformationTextAreaLabel  matlab.ui.control.Label
        RelevantInformationTextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
        simulated % Description
    end
    
    methods (Access = private)
        
        function getModelValues(app)
            %vDCFromModel =  get_param([bdroot,'/Vdc'],'Value');
            
            %app.VEditField_11.Value =  str2double(vDCFromModel);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
           
            app.callingApp = mainApp;
            movegui(app.GridLaboratoryUIFigure,'center');
            load_system('simulation\RTInductionMotorFeb10.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            set_param([bdroot,'/SourceSelect'],'Value',num2str(0));
            %getModelValues(app);
        end

        % Close request function: GridLaboratoryUIFigure
        function GridLaboratoryUIFigureCloseRequest(app, event)
            app.callingApp.Laboratory1Button.Enable = 'on';
            delete(app)
            
        end

        % Callback function
        function SetParametersButtonPushed(app, event)
            app.SetParametersButton.Enable = 'off';        
            
                       
            Rs = num2str(app.RsEditField.Value);
            Ls = num2str(app.XsEditField.Value);
            % Rr = num2str(app.RrEditField.Value); Plz add a textBox for Rr
            % and call it this way ^
            Lr = num2str(app.XrEditField.Value);
            Lm = num2str(app.XmEditField.Value);
            
            set_param([bdroot,'/Motor Model/Rs (ohms)'],'Value',Rs);
            set_param([bdroot,'/Motor Model/Ls (H)'],'Value',Ls);
            % set_param([bdroot,'/Motor Model/Rr (ohms)'],'Value',Rr);
            set_param([bdroot,'/Motor Model/Lr (H)'],'Value',Lr);
            set_param([bdroot,'/Motor Model/Lm (H)'],'Value',Lm);
            
            save_system('simulation\RTInductionMotorFeb10.slx');
            
        end

        % Callback function
        function FrequencySlider_2ValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
             
            freqSlider = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/Motor Model/Freq'],'Value', freqSlider);
        end

        % Callback function
        function PolesSpinner_2ValueChanging(app, event)
            changingValue = event.Value; % reads the value changed in the event happening
            
            polesSpinner = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
            
            set_param([bdroot,'/Motor Model/Poles'],'Value', polesSpinner);
        end

        % Callback function
        function StartButtonPushed(app, event)
            app.StartButton.Enable ='off';
            app.simulated = sim("simulation\RTInductionMotorFeb10.slx");
            
            
            if (get_param(bdroot,'SimulationStatus') == 'stopped')
                app.StartButton.Enable ='on';
            end
        end

        % Callback function
        function VrmsGridValueChanging(app, event)
            changingValue = event.Value;
            
            voltKnob = num2str(changingValue); % The constant boxes in Simulink need a string as an 
            % input in their "Block Parameters: -NameOfBlock-" menu, under "Constant value"
              
            set_param([bdroot,'/GridVoltage'],'Value', voltKnob);
            % getModelValues(app);
        end

        % Button pushed function: SetParametersButton
        function SetParametersButtonPushed2(app, event)
            %app.SimulinkHandle = getSimulinkBlockHandle('RTinductionmotorJan16');
            load_system('simulation\RTinductionmotorFeb10LastVersionNoPWM.slx');   %loads the system into temp memory
            % so that the command 'bdroot' returns the last Simulink model loaded
            
            
            %load_system("C:\Users\gabri\Documents\MATLAB\SandBox\AppDesignerWithSimulink\AppDesignerWithSimulink");
            % if we want to put the Simulink in another folder, we have to
            % specify its complete path as the example shown above
        end

        % Value changed function: FrequencySlider
        function FrequencySliderValueChanged(app, event)
            valueslider = num2str(round(app.FrequencySlider.Value));%avoids gettings values like 45.4168751
            app.EditFrequence.Value=valueslider;
        end

        % Value changed function: EditFrequence
        function EditFrequenceValueChanged(app, event)
            valueEdit = app.EditFrequence.Value;
            app.FrequencySlider.Value=str2double(valueEdit);
            
        end

        % Value changed function: LoadMotor
        function LoadMotorValueChanged(app, event)
            valueload = num2str(round(app.LoadMotor.Value));
            app.ValueLoadMotor.Value=valueload;
            
        end

        % Value changed function: ValueLoadMotor
        function ValueLoadMotorValueChanged(app, event)
            valueload2 = str2double(app.ValueLoadMotor.Value);
            app.LoadMotor.Value=valueload2;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GridLaboratoryUIFigure = uifigure('Visible', 'off');
            app.GridLaboratoryUIFigure.Name = 'Grid Laboratory';
            app.GridLaboratoryUIFigure.CloseRequestFcn = createCallbackFcn(app, @GridLaboratoryUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.GridLaboratoryUIFigure);
            app.MotorParametersTab = uitab(app.TabGroup);
            app.MotorParametersTab.Title = 'Motor Parameters';
            app.GridLayout5 = uigridlayout(app.MotorParametersTab);
            app.Panel2 = uipanel(app.GridLayout5);
            app.Panel2.Title = 'Panel2';
            app.GridLayout7 = uigridlayout(app.Panel2);
            app.FrequencySliderLabel = uilabel(app.GridLayout7);
            app.FrequencySliderLabel.Text = 'Frequency ';
            app.FrequencySlider = uislider(app.GridLayout7);
            app.FrequencySlider.ValueChangedFcn = createCallbackFcn(app, @FrequencySliderValueChanged, true);
            app.FrequencySlider.Value = 30;
            app.EditFrequence = uieditfield(app.GridLayout7, 'text');
            app.EditFrequence.ValueChangedFcn = createCallbackFcn(app, @EditFrequenceValueChanged, true);
            app.PolesSpinnerLabel = uilabel(app.GridLayout7);
            app.PolesSpinnerLabel.Text = 'Poles';
            app.PolesSpinner = uispinner(app.GridLayout7);
            app.PolesSpinner.Limits = [0 Inf];
            app.Image = uiimage(app.GridLayout5);
            app.Image.ImageSource = 'EquivalentCircuitDiagram.png';
            app.PHASEINDUCTIONMOTORLabel = uilabel(app.GridLayout5);
            app.PHASEINDUCTIONMOTORLabel.Text = '3-PHASE INDUCTION MOTOR';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'* The values shown are pre-set values. They can be change at any given moment during the lab experiment'};
            app.PWMInverterPanel = uipanel(app.GridLayout5);
            app.PWMInverterPanel.Title = 'PWM Inverter';
            app.GridLayout10 = uigridlayout(app.PWMInverterPanel);
            app.Panel5 = uipanel(app.GridLayout10);
            app.Panel5.Title = 'Panel5';
            app.GridLayout13 = uigridlayout(app.Panel5);
            app.VEditField_11Label = uilabel(app.GridLayout13);
            app.VEditField_11Label.Text = 'V';
            app.VEditField_11 = uieditfield(app.GridLayout13, 'text');
            app.mfEditFieldLabel = uilabel(app.GridLayout13);
            app.mfEditFieldLabel.Text = 'mf';
            app.mfEditField = uieditfield(app.GridLayout13, 'text');
            app.maEditFieldLabel = uilabel(app.GridLayout13);
            app.maEditFieldLabel.Text = 'ma';
            app.maEditField = uieditfield(app.GridLayout13, 'text');
            app.VoltageVKnob = uiknob(app.GridLayout10, 'continuous');
            app.VoltageVKnob.Limits = [0 250];
            app.VbLamp_11 = uilamp(app.GridLayout10);
            app.VALabel = uilabel(app.GridLayout10);
            app.VALabel.Text = 'VA';
            app.VbLamp_12 = uilamp(app.GridLayout10);
            app.VbLamp_13 = uilamp(app.GridLayout10);
            app.VBLabel = uilabel(app.GridLayout10);
            app.VBLabel.Text = 'VB';
            app.VCLabel = uilabel(app.GridLayout10);
            app.VCLabel.Text = 'VC';
            app.MotorLoadPanel_2 = uipanel(app.GridLayout5);
            app.MotorLoadPanel_2.Title = 'Motor Load';
            app.GridLayout10_2 = uigridlayout(app.MotorLoadPanel_2);
            app.LoadKnob_2Label = uilabel(app.GridLayout10_2);
            app.LoadKnob_2Label.Text = 'Load';
            app.LoadMotor = uiknob(app.GridLayout10_2, 'continuous');
            app.LoadMotor.Limits = [0 9000];
            app.LoadMotor.ValueChangedFcn = createCallbackFcn(app, @LoadMotorValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.GridLayout10_2);
            app.ValueEditFieldLabel.Text = 'Value';
            app.ValueLoadMotor = uieditfield(app.GridLayout10_2, 'text');
            app.ValueLoadMotor.ValueChangedFcn = createCallbackFcn(app, @ValueLoadMotorValueChanged, true);
            app.SetParametersButton = uibutton(app.GridLayout5, 'push');
            app.SetParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SetParametersButtonPushed2, true);
            app.SetParametersButton.Text = 'Set Parameters';
            app.Panel = uipanel(app.GridLayout5);
            app.Panel.Title = 'Panel';
            app.GridLayout6 = uigridlayout(app.Panel);
            app.RsEditFieldLabel = uilabel(app.GridLayout6);
            app.RsEditFieldLabel.Text = 'Rs';
            app.RsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RsEditField.Value = 1;
            app.XsEditFieldLabel = uilabel(app.GridLayout6);
            app.XsEditFieldLabel.Text = 'Xs';
            app.XsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XsEditField.Value = 0.003883;
            app.XmEditFieldLabel = uilabel(app.GridLayout6);
            app.XmEditFieldLabel.Text = 'Xm';
            app.XmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XmEditField.Value = 0.1485;
            app.XrEditFieldLabel = uilabel(app.GridLayout6);
            app.XrEditFieldLabel.Text = 'Xr';
            app.XrEditField = uieditfield(app.GridLayout6, 'numeric');
            app.XrEditField.Value = 0.009061;
            app.RrsEditFieldLabel = uilabel(app.GridLayout6);
            app.RrsEditFieldLabel.Text = 'Rr/s';
            app.RrsEditField = uieditfield(app.GridLayout6, 'numeric');
            app.RrsEditField.Value = 1.06;
            app.GraphicalResultsTab = uitab(app.TabGroup);
            app.GraphicalResultsTab.Title = 'Graphical Results';
            app.GridLayout4 = uigridlayout(app.GraphicalResultsTab);
            app.ExportDropDownLabel = uilabel(app.GridLayout4);
            app.ExportDropDownLabel.Text = 'Export';
            app.ExportDropDown = uidropdown(app.GridLayout4);
            app.ExportDropDown.Items = {'Data (Excel)', 'Data (txt)', 'Images (pdf)', 'Images (word)'};
            app.ExportDropDown.Value = 'Data (Excel)';
            app.UIAxes_Stator_current = uiaxes(app.GridLayout4);
            title(app.UIAxes_Stator_current, 'Stator Current')
            xlabel(app.UIAxes_Stator_current, 'Time (s)')
            ylabel(app.UIAxes_Stator_current, 'Amps')
            app.UIAxes_Rotor_current = uiaxes(app.GridLayout4);
            title(app.UIAxes_Rotor_current, 'Rotor Current')
            xlabel(app.UIAxes_Rotor_current, 'Time (s)')
            ylabel(app.UIAxes_Rotor_current, 'Amps')
            app.UIAxes_EM_torque = uiaxes(app.GridLayout4);
            title(app.UIAxes_EM_torque, 'Electro-Magnetic Torque')
            xlabel(app.UIAxes_EM_torque, 'Time (s)')
            ylabel(app.UIAxes_EM_torque, 'Amps')
            app.UIAxes_speed_vs_torque = uiaxes(app.GridLayout4);
            title(app.UIAxes_speed_vs_torque, 'Speed vs Torque')
            xlabel(app.UIAxes_speed_vs_torque, 'N.m')
            ylabel(app.UIAxes_speed_vs_torque, 'rpm')
            app.UIAxes_pwm = uiaxes(app.GridLayout4);
            title(app.UIAxes_pwm, 'PWM')
            xlabel(app.UIAxes_pwm, 'Time (s)')
            ylabel(app.UIAxes_pwm, 'volts')
            app.ReadMeTab = uitab(app.TabGroup);
            app.ReadMeTab.Title = 'Read Me';
            app.GridLayout9 = uigridlayout(app.ReadMeTab);
            app.ModeTextAreaLabel = uilabel(app.GridLayout9);
            app.ModeTextAreaLabel.Text = 'Mode';
            app.ModeTextArea = uitextarea(app.GridLayout9);
            app.ModeTextArea.Editable = 'off';
            app.ModeTextArea.Value = {'The mode pannel is composed of two lights: green and red. The green corresponds to the working mode or "on" mode. The purpose of this app is to emulate an electric drive motor. This visual only shows the student if the speed is correct, such that they do not go into generation mode.'};
            app.MotorTextAreaLabel = uilabel(app.GridLayout9);
            app.MotorTextAreaLabel.Text = 'Motor';
            app.MotorTextArea = uitextarea(app.GridLayout9);
            app.MotorTextArea.Editable = 'off';
            app.MotorTextArea.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.PWMTextAreaLabel = uilabel(app.GridLayout9);
            app.PWMTextAreaLabel.Text = 'PWM';
            app.PWMTextArea = uitextarea(app.GridLayout9);
            app.PWMTextArea.Editable = 'off';
            app.HelpTextAreaLabel = uilabel(app.GridLayout9);
            app.HelpTextAreaLabel.Text = 'Help';
            app.HelpTextArea = uitextarea(app.GridLayout9);
            app.HelpTextArea.Editable = 'off';
            app.HelpTextArea.Value = {'This option will allow you the student to contact administrators, or security in case of urgence.'};
            app.MotorTextArea_2Label = uilabel(app.GridLayout9);
            app.MotorTextArea_2Label.Text = 'Motor';
            app.MotorTextArea_2 = uitextarea(app.GridLayout9);
            app.MotorTextArea_2.Editable = 'off';
            app.MotorTextArea_2.Value = {'In order to get visual results, we first need to inputs the parameters of the motor. Once they have been set and saved, the student will be able to go into the "Graphical Results". If the button "set parameters" has not been set, the latest will not open. (To be implemented once I found out how to send the information to the model).'};
            app.RelevantInformationTextAreaLabel = uilabel(app.GridLayout9);
            app.RelevantInformationTextAreaLabel.Text = 'Relevant Information';
            app.RelevantInformationTextArea = uitextarea(app.GridLayout9);
            app.RelevantInformationTextArea.Editable = 'off';
            app.RelevantInformationTextArea.Value = {'Concordia Security'; ''; '514-848-3717'; ''; 'Dial 1 for urgent situations'; 'Dial 2 for non-urgent situations'; ''; 'In case of emergency, contact 9-1-1'; ''; 'For Licensing questions: inquiry@eruditio.com'};
            % Show the figure after all components are created
            app.GridLaboratoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GridLab(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GridLaboratoryUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GridLaboratoryUIFigure)
        end
    end
end